classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ButtonQuitProgram          matlab.ui.control.Button
        Panel                      matlab.ui.container.Panel
        ShowHoseiTableButton       matlab.ui.control.Button
        ManualsettingCheckBox      matlab.ui.control.CheckBox
        EditFieldEdgePeriod        matlab.ui.control.NumericEditField
        Label_5                    matlab.ui.control.Label
        EditFieldEdgeStartPoint    matlab.ui.control.NumericEditField
        Label_4                    matlab.ui.control.Label
        EditFieldEdgeDansa         matlab.ui.control.NumericEditField
        Label_3                    matlab.ui.control.Label
        EditFieldDarkCurToBeAdded  matlab.ui.control.NumericEditField
        VEditFieldLabel            matlab.ui.control.Label
        x1Panel                    matlab.ui.container.Panel
        EditFieldScanSpeed         matlab.ui.control.NumericEditField
        mmsecEditFieldLabel        matlab.ui.control.Label
        EditFieldNumBeam           matlab.ui.control.NumericEditField
        HeadEditFieldLabel         matlab.ui.control.Label
        EditFieldNumScan           matlab.ui.control.NumericEditField
        Label_2                    matlab.ui.control.Label
        EditFieldSensorPitch       matlab.ui.control.NumericEditField
        mmEditFieldLabel           matlab.ui.control.Label
        EditFieldNumhead           matlab.ui.control.NumericEditField
        Label                      matlab.ui.control.Label
        ButtonSetMesCond           matlab.ui.control.Button
        CheckBoxNewLevelAlgo       matlab.ui.control.CheckBox
        CheckBoxDrawAllHeadData    matlab.ui.control.CheckBox
        ButtonHasuRescaling        matlab.ui.control.Button
        CheckBoxPDAddDarkCur       matlab.ui.control.CheckBox
        CheckBoxPD05VScaling       matlab.ui.control.CheckBox
        PanelProcessMain           matlab.ui.container.Panel
        EditFieldColorScaleMax     matlab.ui.control.NumericEditField
        Label_7                    matlab.ui.control.Label
        EditFieldColorScaleMin     matlab.ui.control.NumericEditField
        Label_6                    matlab.ui.control.Label
        ColorScaleButtonGroup      matlab.ui.container.ButtonGroup
        rbutton_defined            matlab.ui.control.RadioButton
        rbutton_minmax             matlab.ui.control.RadioButton
        rbutton_zeroone            matlab.ui.control.RadioButton
        ButtonGrayMap              matlab.ui.control.Button
        ButtonColorMap1            matlab.ui.control.Button
        ButtonHasuNoHana           matlab.ui.control.Button
        PanelDatafile              matlab.ui.container.Panel
        LabelMes1                  matlab.ui.control.Label
        DataFileEditField          matlab.ui.control.EditField
        DataFileEditFieldLabel     matlab.ui.control.Label
        ButtonReadDataFile         matlab.ui.control.Button
    end

    % 
    properties (Access = private)
        linenum
        icenterpos
        icenterposhosei
        numhead
        iscannum
    end

    properties (Access = private)
        HoseiTableWindow matlab.ui.Figure
        TableGrid   matlab.ui.container.GridLayout
        TableCtrl   matlab.ui.control.Table
        BtnApply    matlab.ui.control.Button
        BtnSave     matlab.ui.control.Button
    end

    % 手動で補正するテーブル表示用
    methods(Access = private)
        function startupTable(app)
            global dc;
            [ia, ib] = size(dc.icenterpos);
            app.numhead = ia;
            app.iscannum = ib;
            app.linenum = (1:app.iscannum)';
            app.icenterpos = dc.icenterpos(1, 1:app.iscannum)';
            app.icenterposhosei = dc.icenterposhosei(1, 1:app.iscannum)';
        end

        function onCellEdit(app, event)
            global dc;
            r = event.Indices(1);
            c = event.Indices(2);
            v = event.NewData;
            if(c==3) 
                app.icenterposhosei(r) = v;
                dc.icenterposhosei(1,r) = v;
            end

            % 色付け
            style = uistyle("BackgroundColor", [0.9 0.8 0.8]);
            for ix = 1:length(app.linenum)
                if(app.icenterposhosei(ix) ~= 0)
                    addStyle(app.TableCtrl, style, 'row', ix);
                end
            end
        end

        function onApplyCorrection(app)
            app.BtnApply.Enable = "off";
            drawnow;
            try
                process04_main2_hosei(2);
                process04_colormap1_redraw();
            catch
            end
            app.BtnApply.Enable = "on";

        end

        function onSaveCorrection(app)
            app.BtnSave.Enable = "off";
            drawnow;
            try
                process04_main2_hosei(1);
            catch
            end
            app.BtnSave.Enable = "on";
            
        end
    end

    %
    methods(Access = private)
        % App (for example, in a ButtonPushed callback)
        function Call_process04_main2(app, pflag)
            % 1) プログレスダイアログを作成
            dlg = uiprogressdlg(app.UIFigure, ...
                'Title', 'エッジ検出', ...
                'Message', '順にデータを切り出しています...', ...
                'Cancelable', true, ...             % ← キャンセルボタンを有効化
                'Indeterminate', 'off');            % 線形進捗にする（未知なら'on'）

            % 2) 進捗更新/キャンセル確認ハンドルを用意
            updateFcn = @(f,msg)localUpdate(dlg,f,msg);
            isCanceled = @() dlg.CancelRequested;

            try
                % 3) 重い関数を呼ぶ（関数側でupdate/cancelを使う）
                result = process04_main2(pflag, updateFcn, isCanceled); %#ok<NASGU>
                % 必要なら結果をUIに反映
            catch ME
                % エラー表示（お好みで）
                uialert(app.UIFigure, ME.message, 'エラー');
            end

            % 5) 後始末
            if isvalid(dlg)
                close(dlg);
            end

            function localUpdate(dlg, frac, msg)
                % frac: 0〜1, msg: 文字列
                if isvalid(dlg)
                    dlg.Value   = max(0,min(1,frac));
                    if nargin >= 3 && ~isempty(msg); dlg.Message = msg; end
                    drawnow limitrate;  % UIの更新を反映
                end
            end
        end
            
    end


    % 処理に関するメソッド
    methods (Access = private)
        
        function results = checkrbColorScale(app)
            global mc;
            results = 0;
            if(app.rbutton_zeroone.Value)
                results = 0;
            elseif(app.rbutton_minmax.Value)
                results = 1;
            elseif(app.rbutton_defined.Value)
                results = 2;
                mc.colorscalingmax = app.EditFieldColorScaleMax.Value;
                mc.colorscalingmin = app.EditFieldColorScaleMin.Value;
            end
            mc.coloscalingtype = results;
        end

        function results = checkWavDivFile(app)
            global mc;
            global fname0;
            [flag_fname, flag_mat, flag_wavdiv] = process04_checkfname(fname0);
            results = 0;
            if(~flag_wavdiv)
                mc.WavDivided3UseWavDivFile =0;
            else
                answer = questdlg('以前にエッジ検出をした結果があります。それを使いますか？', ...  % ダイアログに表示する質問
                    '確認', ...                % ダイアログのタイトル
                    'Yes', 'No', 'No');        % ボタン（既定は "No"）

                % 結果の判定
                switch answer
                    case 'Yes'
                        mc.WavDivided3UseWavDivFile =1;
                        results = 1;
                        % Yes のときの処理
                    case 'No'
                        mc.WavDivided3UseWavDivFile =0;
                        % No のときの処理
                    otherwise
                        mc.WavDivided3UseWavDivFile =0;
                        % キャンセルや閉じた場合の処理
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)
            % アプリ開始時にはじめに呼ばれる処理

            % 基となるコンポーネントを更新するには、この関数を使用します
            global mc;
            global dc;
            initglobalval();
            process04_common();
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % 変更パラメータについては、ここで
            mc.sensorangle = 0.0;
            mc.sfcutoff = 2; % 背景除去のLPFのカットオフ（単位はmm）
            mc.ycutmax = 2.0; %　(V) 背景除去後、これよりも大きい値のデータはゴミとして扱う

            mc.scanv = 200;
            mc.scanpitch = 0.05;

            % 触らなくていよいパラメータ
            % Si基板の透過率
            mc.substrate_trans = 0.5; % Si基板の透過率 0.5だと50%透過。（Siのコーティングなしだと、こんなもん）
            mc.xstart = 0; % 0を入れる（互換性保持のため残しています）
            mc.xrange = 320;
            
            % 1ch特有の変数
            mc.numhead = 1;
            mc.numbeam = 1;
            mc.iscannum = 6200;
            mc.WavDivided3UseWavDivFile = 1; %以前に処理したことがあるなら使え
            dc.dxa0_init = 0.2e-3;
            mc.pXres = 0.06;

            % Si基板の透過率
            mc.substrate_trans = 0.5; % Si基板の透過率 0.5だと50%透過。（Siのコーティングなしだと、こんなもん）


            % 20251104処理用
            % dc.dxa0_init = 1e-3;
            % mc.scanv = 100; %200;
            % mc.scanpitch = 0.5; %0.05;
            % mc.iscannum = 620;
            % mc.WavDivided3Period = 5600;
            % mc.threshold = 0.07;
            % mc.pXres = 0.11; % ピクセルの横X方向の分解能（箱の横幅）


            %%%%%%%%%%%%%%%%%%%%%%%%%%%% GUIまわり
            % 名称
            app.UIFigure.Name = append('NSS-オプトクエスト データ表示用ソフト 1センサ用', ...
                mc.versionstr);

            app.DataFileEditField.Value = 'initialized';
            app.LabelMes1.Text = 'Read Data Fileボタンを押して、読み込むcsvデータを選択してください';
            
            % 描画パネルをオフに
            app.PanelProcessMain.Enable = "off";
            
            % process04_common()で設定された初期値、設定値を画面に代入
            app.EditFieldNumScan.Value = mc.iscannum;
            app.EditFieldScanSpeed.Value = mc.scanv;
            app.EditFieldSensorPitch.Value = mc.scanpitch;
            %app.DataFieldWaferLossdB.Value = num2str(mc.scanpitch);
            app.EditFieldNumBeam.Value = mc.numbeam;
            app.EditFieldNumhead.Value = mc.numhead;
            app.ButtonSetMesCond.Enable = "off";
            app.EditFieldEdgeDansa.Value = mc.threshold;
            app.EditFieldEdgeStartPoint.Value = mc.WavDivided3StartPoint;
            app.EditFieldEdgePeriod.Value = mc.WavDivided3Period;
            
            app.CheckBoxPD05VScaling.Value = mc.dBscalingEnabled;
            app.CheckBoxPDAddDarkCur.Value = mc.PDAddDarkCurEnabled;
            app.CheckBoxNewLevelAlgo.Value = mc.NewScalingEnabled;
            app.EditFieldDarkCurToBeAdded.Value = mc.PDDardCurAdded;  

            pause(0.1);
            movegui(app.UIFigure, 'onscreen');
        end

        % Button down function: PanelDatafile
        function PanelDatafileButtonDown(app, event)
           
        end

        % Button pushed function: ButtonHasuNoHana
        function ButtonHasuNoHanaPushed(app, event)
            global dc;
            if(dc.divided)
                process04_hasu1();            
            end
        end

        % Button pushed function: ButtonColorMap1
        function ButtonColorMap1Pushed(app, event)
            checkrbColorScale(app);
            type = 1; % jet
            process04_colormap1(type, 1);
                        
            global mc;
            if(mc.coloscalingtype ~= 0)
                app.EditFieldColorScaleMax.Value = mc.colorscalingmax;
                app.EditFieldColorScaleMin.Value = mc.colorscalingmin;
            end
        end

        % Button pushed function: ButtonGrayMap
        function ButtonGrayMapPushed(app, event)
            checkrbColorScale(app);
            process04_colormap1(0, 1);
            
            global mc;
            if(mc.coloscalingtype ~= 0)
                app.EditFieldColorScaleMax.Value = mc.colorscalingmax;
                app.EditFieldColorScaleMin.Value = mc.colorscalingmin;
            end
        end

        % Button pushed function: ButtonReadDataFile
        function ButtonReadDataFilePushed(app, event)
            global dc;
            global mc;
            
            [flag, fname] = FileDialog02();
            app.DataFileEditField.Value = fname; 
            if(flag ~= 0) 
                return; %キャンセルが押された場合
            end
            app.ButtonReadDataFile.Enable = "off";
            app.LabelMes1.Text = 'ファイル読み込み中　5分くらいお待ちください';
            drawnow;
            process04_readdatafile1(fname);
            app.ButtonReadDataFile.Enable = "on";
            app.LabelMes1.Text = '読み込みました';

            drawnow;
            movegui(app.UIFigure, 'onscreen');

            if(~dc.stored) % もしデータが読み込まれていなかったら戻る
                app.LabelMes1.Text = '正しく読み込めませんでした。データファイルが正しいかチェックしてください。';
                return;
            end

            app.PanelProcessMain.Enable = "on";
            app.EditFieldNumBeam.Value = mc.numbeam;
            app.EditFieldNumhead.Value = mc.numhead;
            app.EditFieldNumScan.Value = mc.iscannum;
            app.EditFieldScanSpeed.Value = mc.scanv; 
            app.EditFieldSensorPitch.Value = mc.scanpitch;

            % 以前に処理したデータがあるか？
            checkWavDivFile(app);

            % センサレベル補正＋エッジ検出 
            try
                app.Panel.Enable = "off";
                mc.dBscalingEnabled = app.CheckBoxPD05VScaling.Value;
                mc.PDAddDarkCurEnabled = app.CheckBoxPDAddDarkCur.Value;
                mc.PDDardCurAdded = app.EditFieldDarkCurToBeAdded.Value;
                mc.NewScalingEnabled = app.CheckBoxNewLevelAlgo.Value;
                mc.WavDivided2AllReadDataDraw = app.CheckBoxDrawAllHeadData.Value;
                Call_process04_main2(app, 0);
                app.Panel.Enable = "on";
            catch
                app.LabelMes1.Text = 'エッジ検出で失敗したかも';
                app.Panel.Enable = "on";
            end

            drawnow;
            movegui(app.UIFigure, 'onscreen');
        end

        % Button pushed function: ButtonHasuRescaling
        function ButtonHasuRescalingPushed(app, event)
            global dc;
            global mc;

            if(~dc.stored) % もしデータが読み込まれていなかったら戻る
                return;
            end
            
            try
                app.Panel.Enable = "off";
                mc.dBscalingEnabled = app.CheckBoxPD05VScaling.Value;
                mc.PDAddDarkCurEnabled = app.CheckBoxPDAddDarkCur.Value;
                mc.PDDardCurAdded = app.EditFieldDarkCurToBeAdded.Value;
                mc.NewScalingEnabled = app.CheckBoxNewLevelAlgo.Value;
                mc.WavDivided2AllReadDataDraw = app.CheckBoxDrawAllHeadData.Value;
                Call_process04_main2(app, 0);
                app.Panel.Enable = "on";
            catch
                app.LabelMes1.Text = 'エッジ検出で失敗したかも';
                app.Panel.Enable = "on";
            end
            
            drawnow;
            movegui(app.UIFigure, 'onscreen');
        end

        % Button pushed function: ButtonQuitProgram
        function ButtonQuitProgramPushed(app, event)
            delete(app);
        end

        % Button pushed function: ShowHoseiTableButton
        function ShowHoseiTableEdit(app, event)
            % 補正テーブルの表示をここに
            startupTable(app);
            
            %
            T = table(app.linenum, app.icenterpos, app.icenterposhosei, ...
                'VariableNames', {'行','中心位置','補正値'});
            
            if isempty(app.HoseiTableWindow) || ~isvalid(app.HoseiTableWindow)
                app.HoseiTableWindow = uifigure('Name','補正テーブル','Position',[100 100 900 600]);

                % --- レイアウト（上：表  下：ボタン行）---
                app.TableGrid = uigridlayout(app.HoseiTableWindow,[2 3]);
                app.TableGrid.RowHeight   = {'1x', 40};
                app.TableGrid.ColumnWidth = {'1x', 'fit', 'fit'};
                app.TableGrid.Padding     = [10 10 10 10];%

                app.TableCtrl = uitable(app.TableGrid, 'Data', T, ...
                    'Tag', 'CorrectionTable',...
                    'ColumnEditable', [false false true], ...
                    'CellEditCallback', @(src,evt)onCellEdit(app, evt));
                app.TableCtrl.Layout.Row = 1;
                app.TableCtrl.Layout.Column = [1 3];
                % 色付け
                style = uistyle("BackgroundColor", [0.9 0.8 0.8]);
                for ix = 1:length(app.linenum)
                    if(app.icenterposhosei(ix) ~= 0)
                        addStyle(app.TableCtrl, style, 'row', ix);
                    end
                end
                
                % --- ボタン：補正値の適用 ---
                app.BtnApply = uibutton(app.TableGrid, 'push', ...
                    'Text', '補正値の適用', ...
                    'ButtonPushedFcn', @(src,evt) onApplyCorrection(app));
                app.BtnApply.Layout.Row = 2;
                app.BtnApply.Layout.Column = 2;

                % --- ボタン：補正値のセーブ ---
                app.BtnSave = uibutton(app.TableGrid, 'push', ...
                    'Text', '補正値のセーブ', ...
                    'ButtonPushedFcn', @(src,evt) onSaveCorrection(app));
                app.BtnSave.Layout.Row = 2;
                app.BtnSave.Layout.Column = 3;

            else
                app.TableCtrl.Data = T;
                figure(app.HoseiTableWindow);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Tag = '2025/07/23';
            app.PanelDatafile = uipanel(app.UIFigure);
            app.PanelDatafile.Title = 'データファイルの読み込み';
            app.PanelDatafile.ButtonDownFcn = createCallbackFcn(app, @PanelDatafileButtonDown, true);
            app.ButtonReadDataFile = uibutton(app.PanelDatafile, 'push');
            app.ButtonReadDataFile.ButtonPushedFcn = createCallbackFcn(app, @ButtonReadDataFilePushed, true);
            app.ButtonReadDataFile.Text = 'Read Data File';
            app.DataFileEditFieldLabel = uilabel(app.PanelDatafile);
            app.DataFileEditFieldLabel.Text = 'Data File';
            app.DataFileEditField = uieditfield(app.PanelDatafile, 'text');
            app.LabelMes1 = uilabel(app.PanelDatafile);
            app.LabelMes1.Text = 'helllo world!';
            app.PanelProcessMain = uipanel(app.UIFigure);
            app.PanelProcessMain.Title = '描画';
            app.ButtonHasuNoHana = uibutton(app.PanelProcessMain, 'push');
            app.ButtonHasuNoHana.ButtonPushedFcn = createCallbackFcn(app, @ButtonHasuNoHanaPushed, true);
            app.ButtonHasuNoHana.Text = '蓮の花表示';
            app.ButtonColorMap1 = uibutton(app.PanelProcessMain, 'push');
            app.ButtonColorMap1.ButtonPushedFcn = createCallbackFcn(app, @ButtonColorMap1Pushed, true);
            app.ButtonColorMap1.Text = 'カラーマップ';
            app.ButtonGrayMap = uibutton(app.PanelProcessMain, 'push');
            app.ButtonGrayMap.ButtonPushedFcn = createCallbackFcn(app, @ButtonGrayMapPushed, true);
            app.ButtonGrayMap.Text = '白黒マップ';
            app.ColorScaleButtonGroup = uibuttongroup(app.PanelProcessMain);
            app.ColorScaleButtonGroup.Title = 'Color Scale';
            app.rbutton_zeroone = uiradiobutton(app.ColorScaleButtonGroup);
            app.rbutton_zeroone.Text = '0, 1 固定';
            app.rbutton_zeroone.Value = true;
            app.rbutton_minmax = uiradiobutton(app.ColorScaleButtonGroup);
            app.rbutton_minmax.Text = '最小値－最大値';
            app.rbutton_defined = uiradiobutton(app.ColorScaleButtonGroup);
            app.rbutton_defined.Text = '値指定';
            app.Label_6 = uilabel(app.PanelProcessMain);
            app.Label_6.Text = '最小値';
            app.EditFieldColorScaleMin = uieditfield(app.PanelProcessMain, 'numeric');
            app.Label_7 = uilabel(app.PanelProcessMain);
            app.Label_7.Text = '最大値';
            app.EditFieldColorScaleMax = uieditfield(app.PanelProcessMain, 'numeric');
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '前処理';
            app.CheckBoxPD05VScaling = uicheckbox(app.Panel);
            app.CheckBoxPD05VScaling.Text = '0.2V/10dB → 0.5V/10dBのスケーリング（新新PD用）';
            app.CheckBoxPDAddDarkCur = uicheckbox(app.Panel);
            app.CheckBoxPDAddDarkCur.Text = '暗電流加算';
            app.ButtonHasuRescaling = uibutton(app.Panel, 'push');
            app.ButtonHasuRescaling.ButtonPushedFcn = createCallbackFcn(app, @ButtonHasuRescalingPushed, true);
            app.ButtonHasuRescaling.Text = 'スケーリングとエッジ検出';
            app.CheckBoxDrawAllHeadData = uicheckbox(app.Panel);
            app.CheckBoxDrawAllHeadData.Text = '全ヘッド描画';
            app.CheckBoxDrawAllHeadData.Value = true;
            app.CheckBoxNewLevelAlgo = uicheckbox(app.Panel);
            app.CheckBoxNewLevelAlgo.Text = '新レベル合わせアルゴリズム適用(test version)';
            app.x1Panel = uipanel(app.Panel);
            app.x1Panel.Title = '装置・スキャン条件（1x1センサ用）';
            app.ButtonSetMesCond = uibutton(app.x1Panel, 'push');
            app.ButtonSetMesCond.Text = '値を再設定（未実装）';
            app.Label = uilabel(app.x1Panel);
            app.Label.Text = 'センサ数（ヘッド数）';
            app.EditFieldNumhead = uieditfield(app.x1Panel, 'numeric');
            app.EditFieldNumhead.Limits = [0 Inf];
            app.mmEditFieldLabel = uilabel(app.x1Panel);
            app.mmEditFieldLabel.Text = 'センサピッチ(mm)';
            app.EditFieldSensorPitch = uieditfield(app.x1Panel, 'numeric');
            app.EditFieldSensorPitch.Limits = [0 Inf];
            app.Label_2 = uilabel(app.x1Panel);
            app.Label_2.Text = 'スキャン数（往復数ｘ２）';
            app.EditFieldNumScan = uieditfield(app.x1Panel, 'numeric');
            app.EditFieldNumScan.Limits = [0 Inf];
            app.HeadEditFieldLabel = uilabel(app.x1Panel);
            app.HeadEditFieldLabel.Text = 'Headあたりビーム数';
            app.EditFieldNumBeam = uieditfield(app.x1Panel, 'numeric');
            app.EditFieldNumBeam.Limits = [0 Inf];
            app.mmsecEditFieldLabel = uilabel(app.x1Panel);
            app.mmsecEditFieldLabel.Text = 'スキャン速度(mm/sec)';
            app.EditFieldScanSpeed = uieditfield(app.x1Panel, 'numeric');
            app.EditFieldScanSpeed.Limits = [0 Inf];
            app.VEditFieldLabel = uilabel(app.Panel);
            app.VEditFieldLabel.Text = '加算する暗電流(V)';
            app.EditFieldDarkCurToBeAdded = uieditfield(app.Panel, 'numeric');
            app.EditFieldDarkCurToBeAdded.Limits = [0 Inf];
            app.EditFieldDarkCurToBeAdded.Value = 0.2;
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = 'エッジ 段差閾値';
            app.EditFieldEdgeDansa = uieditfield(app.Panel, 'numeric');
            app.EditFieldEdgeDansa.Limits = [0.001 Inf];
            app.EditFieldEdgeDansa.Value = 0.2;
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = 'エッジ検出開始点';
            app.EditFieldEdgeStartPoint = uieditfield(app.Panel, 'numeric');
            app.EditFieldEdgeStartPoint.Limits = [1 Inf];
            app.EditFieldEdgeStartPoint.Value = 1;
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Text = '周期（エッジ間隔）';
            app.EditFieldEdgePeriod = uieditfield(app.Panel, 'numeric');
            app.EditFieldEdgePeriod.Value = 3000;
            app.ManualsettingCheckBox = uicheckbox(app.Panel);
            app.ManualsettingCheckBox.Text = {'Manual '; 'setting'};
            app.ShowHoseiTableButton = uibutton(app.Panel, 'push');
            app.ShowHoseiTableButton.ButtonPushedFcn = createCallbackFcn(app, @ShowHoseiTableEdit, true);
            app.ShowHoseiTableButton.Text = '手動で位置補正';
            app.ButtonQuitProgram = uibutton(app.UIFigure, 'push');
            app.ButtonQuitProgram.ButtonPushedFcn = createCallbackFcn(app, @ButtonQuitProgramPushed, true);
            app.ButtonQuitProgram.Text = '終了';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end